classdef ecualizador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        dBLabel_6             matlab.ui.control.Label
        dBLabel_5             matlab.ui.control.Label
        dBLabel_4             matlab.ui.control.Label
        dBLabel_3             matlab.ui.control.Label
        dBLabel_2             matlab.ui.control.Label
        dBLabel               matlab.ui.control.Label
        BrillianceLabel       matlab.ui.control.Label
        brilliance            matlab.ui.control.Slider
        PresenceLabel         matlab.ui.control.Label
        presence              matlab.ui.control.Slider
        HighMidsLabel         matlab.ui.control.Label
        highmids              matlab.ui.control.Slider
        LowMidsLabel          matlab.ui.control.Label
        lowmids               matlab.ui.control.Slider
        BassLabel             matlab.ui.control.Label
        bass                  matlab.ui.control.Slider
        SubbassLabel          matlab.ui.control.Label
        subbass               matlab.ui.control.Slider
        ButtonGroup           matlab.ui.container.ButtonGroup
        expansion             matlab.ui.control.RadioButton
        decimacion            matlab.ui.control.RadioButton
        FactorLabel           matlab.ui.control.Label
        factor                matlab.ui.control.Spinner
        EjecutarButton        matlab.ui.control.Button
        brilliancefrecuencia  matlab.ui.control.UIAxes
        presencefrecuencia    matlab.ui.control.UIAxes
        highmidsfrecuencia    matlab.ui.control.UIAxes
        lowmidsfrecuencia     matlab.ui.control.UIAxes
        bassfrecuencia        matlab.ui.control.UIAxes
        subbassfrecuencia     matlab.ui.control.UIAxes
        brilliancetiempo      matlab.ui.control.UIAxes
        presencetiempo        matlab.ui.control.UIAxes
        highmidstiempo        matlab.ui.control.UIAxes
        lowmidstiempo         matlab.ui.control.UIAxes
        basstiempo            matlab.ui.control.UIAxes
        subbasstiempo         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            clc;
    close all;
N = app.factor.Value;
%% Señal Original x[n]
name_a="counting_stars_cut.wav";
%Informacion del audio original

info_x = audioinfo(name_a)


[audio, fs] = audioread(name_a);

%%
audio = 0.5*(audio(:, 1) + audio(:, 2)).';  %%Audio estereo a mono

duracion_audio = length(audio) / fs;   %%duración

n_samples = length(audio);  %%num_muestras


%%
vec_xt = 0:seconds(1/fs): seconds(duracion_audio);
vec_xt = vec_xt(1:end-1);

figure(1)
subplot(3,1,1);
plot(vec_xt,transpose(audio));
title("Audio x(t)");
xlabel("Tiempo");
ylabel("Amplitud");
grid on;
grid minor;

figure(1)
subplot(3,1,2);
vec_nx = 1:n_samples;
stem(vec_nx, audio);
title("Audio x[n]");
xlabel("Tiempo (Solo un Intervalo de muestras)");
%xlim([500000 500100])
ylabel("Amplitud");
grid on;
grid minor;


%% Transformada de Fourier del audio original
X = fft(audio, n_samples)/n_samples;  
Xi = fftshift(X);

frec_X = linspace(-fs/2, fs/2, length(X));

figure(1);
subplot(3,1,3);
plot(frec_X, 2*abs(Xi));
title("Espectro de frecuencias de x[n]: $X(e^{j\omega})$", 'Interpreter', 'latex');
xlabel("frecuencia [Hz]");
ylabel("Amplitud");

grid on;
grid minor;

A = app.bass.Value;
B = app.subbass.Value;

%% Decimación

M = N;
% Filtro Anti-aliasing
Hdec_LPF = abs(frec_X)<= (fs/(2*M));

% Filtrado para decimación
X_filtered = (Xi .* Hdec_LPF);

x_filt = ifft(X_filtered*n_samples); 

%x_filt = real(x_filt);

% Operacion de decimación
f_dec = fs / M;
muestras_dec = n_samples / M;

y_dec = zeros;
for n = 1:muestras_dec
    y_dec(n) = (x_filt(n*M));
end


%%%FFT de la señal diezmada
Y_dec = fft(y_dec)/round(muestras_dec);
Yi_dec = fftshift(Y_dec);

frecY_dec = linspace(-fs/2, fs/2, muestras_dec);


%%% Graficas de decimación

figure(2);
subplot(4,1,1);
plot(frec_X, abs(X_filtered)/max(abs(X_filtered)));
hold on;
plot(frec_X, Hdec_LPF,'r');
title("Espectro (normalizado) de X por el filtro anti aliasing $X(e^{j\omega}) . H(e^{j\omega})$", 'Interpreter', 'latex');
xlabel("Frecuencia [Hz]");
ylabel("Amplitud");
ylim([-0.1 1.1])
grid on; grid minor;
ax = gca;

tdec = 0:seconds(1/f_dec): seconds(duracion_audio);
tdec = tdec(1:end-1);
figure(2)
subplot(4,1,2);
plot(tdec, real(y_dec*(-1)));
title("Señal x(t) diezmada");
xlabel("Tiempo");
ylabel("Amplitud");
grid on;
grid minor;

figure(2)
subplot(4,1,3);
nx_dec = 1:muestras_dec;
stem(nx_dec, real(y_dec*(-1)));
title("Señal x[n] diezmada");
xlabel("Tiempo (Solo un Intervalo de muestras)");
%xlim([500000 500100]);
ylabel("Amplitud");
grid on;
grid minor;

figure(2);
subplot(4,1,4);
plot(frecY_dec, 2*abs(Yi_dec));
title("Espectro de frecuencias de la señal diezmada");
xlabel("frecuencia [Hz]");
ylabel("Amplitud");

%%%%%%

%% Expansión / Interpolación 

L = N;  %%factor de interpolación 

f_interp = fs*L;
muestras_interp = n_samples*L;

%%% Operación de interpolación 

y_expand = zeros;

for n = 1:muestras_interp
    if mod(n,L) == 0
        y_expand(n) = audio(n/L);
    else
        y_expand(n) = 0;
    end
end


%%% FFT de la señal expandida y filtrado
Y_expand = fft(y_expand, muestras_interp)/muestras_interp;  

Yi_expand = fftshift(Y_expand);

frecY_expand = linspace(-fs/2, fs/2, muestras_interp);

%%% Filtro de interpolación
Hexp_LPF = abs(frecY_expand) <= (fs/L)*(0.5);

Yexp_filt = Yi_expand .* Hexp_LPF;


figure(3);
subplot(4,1,1);
plot(frecY_expand, 2*abs(Yi_expand));
title("Espectro de frecuencias la señal expandida");
xlabel("frecuencia [Hz]");
ylabel("Amplitud");

figure(3);
subplot(4,1,2);
plot(frecY_expand, Hexp_LPF, 'r');
hold on;
plot(frecY_expand, abs(Yexp_filt)/max(abs(Yexp_filt)), 'b');
title("Espectro de la señal expandida y filtrada");
xlabel("frecuencia [Hz]");
ylabel("Amplitud");


%% Interpolacion en el tiempo;
yexp_filtered = ifft(fftshift(Yexp_filt*muestras_interp));

texp = 0:seconds(1/f_interp): seconds(duracion_audio);
texp = texp(1:length(yexp_filtered));

disp(y_expand(1:10));
disp(yexp_filtered(1:10));

figure(3)
subplot(4,1,3);
plot(texp, real(yexp_filtered));
title("Señal x(t) Expandida");
xlabel("Tiempo");
ylabel("Amplitud");
grid on;
grid minor;

figure(3)
subplot(4,1,4);
ny_exp = 1:muestras_interp;
stem(ny_exp, real(yexp_filtered));
title("Señal x[n] expandida");
xlabel("Tiempo (Solo un Intervalo de muestras)");
%xlim([500000 500100]);
ylabel("Amplitud");
grid on;
grid minor;


if app.decimacion.Value == 1
    %%Salida de las FFT de la señales expandidas o decimadas

    Y_in = Yi_dec;     %%decimación

    %%Frecuencias de sampleo de la decimación o interpolacion

    f_in = f_dec;      %%decimación

    %%Rango de frecuencias de la decimación o interpoladas

    frec_Y = frecY_dec;     %%decimación
    muestras_Y = muestras_dec;

end
%% Se escoge que datos salen decimacion o expansion para ecualizar

if app.expansion.Value == 1
    %%Salida de las FFT de la señales expandidas o decimadas
    Y_in = Yexp_filt;

    %%Frecuencias de sampleo de la decimación o interpolacion
    f_in = f_interp;

    %%Rango de frecuencias de la decimación o interpoladas
    frec_Y = frecY_expand;
    muestras_Y = muestras_interp;

end

%% Ecualizadores 
%% Ganancias  que salen de la app
G1 =10^(app.bass.Value/20);  %% G = 10^(valor de la app/20);
G2 =10^(app.subbass.Value/20);
G3 =10^(app.lowmids.Value/20);
G4 =10^(app.highmids.Value/20);
G5 =10^(app.presence.Value/20);
G6 =10^(app.brilliance.Value/20);


%%Sub-Bass
Sub = ((abs(frec_Y)>=16).*(abs(frec_Y)<=60));
Y_sub = G1*(Y_in.* Sub);
%%Bass
Bass = ((abs(frec_Y)>=60).*(abs(frec_Y)<=250));
Y_bass = G2*(Y_in.* Bass);
%%Low Mid
L_Mid = ((abs(frec_Y)>=250).*(abs(frec_Y)<=2e3));
Y_Lmid = G3*(Y_in.* L_Mid);
%%High Mid
H_Mid = ((abs(frec_Y)>=2e3).*(abs(frec_Y)<=4e3));
Y_Hmid = G4*(Y_in.* H_Mid);
%%Presence
Pres = ((abs(frec_Y)>=4e3).*(abs(frec_Y)<=6e3));
Y_pres = G5*(Y_in.* Pres);
%Brrilliance 
Brillo = ((abs(frec_Y)>=6e3).*(abs(frec_Y)<=16e3));
Y_brillo = G6*(Y_in.* Brillo);

Z_EQ = Y_sub + Y_bass + Y_Lmid + Y_Hmid + Y_pres + Y_brillo; %FFT total del equalizador


z_eq_t = ifft(fftshift(Z_EQ*muestras_Y));  %%salida del ecualizador en el tiempo

t_eq = 0:seconds(1/f_in): seconds(duracion_audio);
t_eq = t_eq(1:length(z_eq_t));

%% Señal en el tiempo de cada banda
z_sub = ifft(fftshift(Y_sub*muestras_Y));
z_bass = ifft(fftshift(Y_bass*muestras_Y));
z_lmid = ifft(fftshift(Y_Lmid*muestras_Y));  
z_hmid = ifft(fftshift(Y_Hmid*muestras_Y));
z_pres = ifft(fftshift(Y_pres*muestras_Y));
z_brillo = ifft(fftshift(Y_brillo*muestras_Y));

%%%graficas

plot(app.subbasstiempo,t_eq, real(z_sub*(-1)));
title(app.subbasstiempo, "Banda Sub-Bass (tiempo)");
xlabel(app.subbasstiempo, "Tiempo");
ylabel(app.subbasstiempo, "Amplitud");
grid(app.subbasstiempo, 'on');
grid(app.subbasstiempo, 'minor');

plot(app.basstiempo,t_eq, real(z_bass*(-1)));
title(app.basstiempo, "Banda Bass (tiempo)");
xlabel(app.basstiempo, "Tiempo");
ylabel(app.basstiempo, "Amplitud");
grid(app.basstiempo, 'on');
grid(app.basstiempo, 'minor');

plot(app.lowmidstiempo,t_eq, real(z_lmid*(-1)));
title(app.lowmidstiempo, "Banda Low Mids (tiempo)");
xlabel(app.lowmidstiempo, "Tiempo");
ylabel(app.lowmidstiempo, "Amplitud");
grid(app.lowmidstiempo, 'on');
grid(app.lowmidstiempo, 'minor');

plot(app.highmidstiempo,t_eq, real(z_hmid*(-1)));
title(app.highmidstiempo, "Banda High Mids (tiempo)");
xlabel(app.highmidstiempo, "Tiempo");
ylabel(app.highmidstiempo, "Amplitud");
grid(app.highmidstiempo, 'on');
grid(app.highmidstiempo, 'minor');


plot(app.presencetiempo,t_eq, real(z_pres*(-1)));
title(app.presencetiempo, "Banda Presence (tiempo)");
xlabel(app.presencetiempo, "Tiempo");
ylabel(app.presencetiempo, "Amplitud");
grid(app.presencetiempo, 'on');
grid(app.presencetiempo, 'minor');

plot(app.brilliancetiempo,t_eq, real(z_brillo*(-1)));
title(app.brilliancetiempo, "Banda Brillance (tiempo)");
xlabel(app.brilliancetiempo, "Tiempo");
ylabel(app.brilliancetiempo, "Amplitud");
grid(app.brilliancetiempo, 'on');
grid(app.brilliancetiempo, 'minor');


%% Espectros de cada una de las bandas del ecualizador (Valores Normalizados)

plot(app.subbassfrecuencia,frec_Y, Sub, 'r');
hold(app.subbassfrecuencia, 'on');  
plot(app.subbassfrecuencia,frec_Y, abs(Y_sub)/max(abs(Y_sub)), 'b');
title(app.subbassfrecuencia,"Espectro de la banda Sub-Bass");
xlabel(app.subbassfrecuencia, "frecuencia [Hz]");
ylabel(app.subbassfrecuencia, "Amplitud");
grid(app.subbassfrecuencia, 'on');
grid(app.subbassfrecuencia, 'minor');

plot(app.bassfrecuencia,frec_Y, Bass, 'r');
hold(app.bassfrecuencia, 'on');  
plot(app.bassfrecuencia,frec_Y, abs(Y_bass)/max(abs(Y_bass)), 'b');
title(app.bassfrecuencia,"Espectro de la banda Bass");
xlabel(app.bassfrecuencia,"frecuencia [Hz]");
ylabel(app.bassfrecuencia,"Amplitud");
grid(app.bassfrecuencia, 'on');
grid(app.bassfrecuencia, 'minor');


plot(app.lowmidsfrecuencia,frec_Y, L_Mid, 'r');
hold(app.lowmidsfrecuencia, 'on');  
plot(app.lowmidsfrecuencia,frec_Y, abs(Y_Lmid)/max(abs(Y_Lmid)), 'b');
title(app.lowmidsfrecuencia,"Espectro de la manda Low Mids");
xlabel(app.lowmidsfrecuencia,"frecuencia [Hz]");
ylabel(app.lowmidsfrecuencia,"Amplitud");
grid(app.lowmidsfrecuencia, 'on');
grid(app.lowmidsfrecuencia, 'minor');


plot(app.highmidsfrecuencia,frec_Y, H_Mid, 'r');
hold(app.highmidsfrecuencia, 'on');  
plot(app.highmidsfrecuencia,frec_Y, abs(Y_Hmid)/max(abs(Y_Hmid)), 'b');
title(app.highmidsfrecuencia,"Espectro de la banda High Mids");
xlabel(app.highmidsfrecuencia,"frecuencia [Hz]");
ylabel(app.highmidsfrecuencia,"Amplitud");
grid(app.highmidsfrecuencia, 'on');
grid(app.highmidsfrecuencia, 'minor');


plot(app.presencefrecuencia, frec_Y, Pres, 'r');
hold(app.presencefrecuencia, 'on');  
plot(app.presencefrecuencia,frec_Y, abs(Y_pres)/max(abs(Y_pres)), 'b');
title(app.presencefrecuencia,"Espectro de la Banda Presence");
xlabel(app.presencefrecuencia,"frecuencia [Hz]");
ylabel(app.presencefrecuencia,"Amplitud");
grid(app.presencefrecuencia, 'on');
grid(app.presencefrecuencia, 'minor');


plot(app.brilliancefrecuencia, frec_Y, Brillo, 'r');
hold(app.brilliancefrecuencia, 'on');  
plot(app.brilliancefrecuencia,frec_Y, abs(Y_brillo)/max(abs(Y_brillo)), 'b');
title(app.brilliancefrecuencia,"Espectro de la banda Brillo");
xlabel(app.brilliancefrecuencia,"frecuencia [Hz]");
ylabel(app.brilliancefrecuencia,"Amplitud");
grid(app.brilliancefrecuencia, 'on');
grid(app.brilliancefrecuencia, 'minor');


 figure(4);
 subplot(2,1,1);
 plot(frec_Y, 2*abs(Z_EQ) , 'b');
 title("Espectro de la señal ecualizada");
 xlabel("frecuencia [Hz]");
 ylabel("Amplitud");
 
 figure(4)
 subplot(2,1,2);
 plot(t_eq, real(z_eq_t*(-1)));
 title("Señal x(t) Ecualizada");
 xlabel("Tiempo");
 ylabel("Amplitud");
 grid on;
 grid minor;
sound(real(z_eq_t),f_in); % Audio filtrado (LPF)
pause( duracion_audio + 1 );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.subbasstiempo = uiaxes(app.UIFigure);
            title(app.subbasstiempo, 'Title')
            xlabel(app.subbasstiempo, 'X')
            ylabel(app.subbasstiempo, 'Y')
            zlabel(app.subbasstiempo, 'Z')
            app.basstiempo = uiaxes(app.UIFigure);
            title(app.basstiempo, 'Title')
            xlabel(app.basstiempo, 'X')
            ylabel(app.basstiempo, 'Y')
            zlabel(app.basstiempo, 'Z')
            app.lowmidstiempo = uiaxes(app.UIFigure);
            title(app.lowmidstiempo, 'Title')
            xlabel(app.lowmidstiempo, 'X')
            ylabel(app.lowmidstiempo, 'Y')
            zlabel(app.lowmidstiempo, 'Z')
            app.highmidstiempo = uiaxes(app.UIFigure);
            title(app.highmidstiempo, 'Title')
            xlabel(app.highmidstiempo, 'X')
            ylabel(app.highmidstiempo, 'Y')
            zlabel(app.highmidstiempo, 'Z')
            app.presencetiempo = uiaxes(app.UIFigure);
            title(app.presencetiempo, 'Title')
            xlabel(app.presencetiempo, 'X')
            ylabel(app.presencetiempo, 'Y')
            zlabel(app.presencetiempo, 'Z')
            app.brilliancetiempo = uiaxes(app.UIFigure);
            title(app.brilliancetiempo, 'Title')
            xlabel(app.brilliancetiempo, 'X')
            ylabel(app.brilliancetiempo, 'Y')
            zlabel(app.brilliancetiempo, 'Z')
            app.subbassfrecuencia = uiaxes(app.UIFigure);
            title(app.subbassfrecuencia, 'Title')
            xlabel(app.subbassfrecuencia, 'X')
            ylabel(app.subbassfrecuencia, 'Y')
            zlabel(app.subbassfrecuencia, 'Z')
            app.bassfrecuencia = uiaxes(app.UIFigure);
            title(app.bassfrecuencia, 'Title')
            xlabel(app.bassfrecuencia, 'X')
            ylabel(app.bassfrecuencia, 'Y')
            zlabel(app.bassfrecuencia, 'Z')
            app.lowmidsfrecuencia = uiaxes(app.UIFigure);
            title(app.lowmidsfrecuencia, 'Title')
            xlabel(app.lowmidsfrecuencia, 'X')
            ylabel(app.lowmidsfrecuencia, 'Y')
            zlabel(app.lowmidsfrecuencia, 'Z')
            app.highmidsfrecuencia = uiaxes(app.UIFigure);
            title(app.highmidsfrecuencia, 'Title')
            xlabel(app.highmidsfrecuencia, 'X')
            ylabel(app.highmidsfrecuencia, 'Y')
            zlabel(app.highmidsfrecuencia, 'Z')
            app.presencefrecuencia = uiaxes(app.UIFigure);
            title(app.presencefrecuencia, 'Title')
            xlabel(app.presencefrecuencia, 'X')
            ylabel(app.presencefrecuencia, 'Y')
            zlabel(app.presencefrecuencia, 'Z')
            app.brilliancefrecuencia = uiaxes(app.UIFigure);
            title(app.brilliancefrecuencia, 'Title')
            xlabel(app.brilliancefrecuencia, 'X')
            ylabel(app.brilliancefrecuencia, 'Y')
            zlabel(app.brilliancefrecuencia, 'Z')
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.factor = uispinner(app.UIFigure);
            app.factor.Limits = [1 10];
            app.factor.Value = 2;
            app.FactorLabel = uilabel(app.UIFigure);
            app.FactorLabel.Text = 'Factor ';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.decimacion = uiradiobutton(app.ButtonGroup);
            app.decimacion.Text = 'Decimación';
            app.decimacion.Value = true;
            app.expansion = uiradiobutton(app.ButtonGroup);
            app.expansion.Text = 'Expansión';
            app.subbass = uislider(app.UIFigure);
            app.subbass.Limits = [-50 50];
            app.subbass.Tooltip = {''};
            app.SubbassLabel = uilabel(app.UIFigure);
            app.SubbassLabel.Text = 'Sub-bass';
            app.bass = uislider(app.UIFigure);
            app.bass.Limits = [-50 50];
            app.BassLabel = uilabel(app.UIFigure);
            app.BassLabel.Text = 'Bass';
            app.lowmids = uislider(app.UIFigure);
            app.lowmids.Limits = [-50 50];
            app.LowMidsLabel = uilabel(app.UIFigure);
            app.LowMidsLabel.Text = 'Low Mids';
            app.highmids = uislider(app.UIFigure);
            app.highmids.Limits = [-50 50];
            app.HighMidsLabel = uilabel(app.UIFigure);
            app.HighMidsLabel.Text = 'High Mids';
            app.presence = uislider(app.UIFigure);
            app.presence.Limits = [-50 50];
            app.PresenceLabel = uilabel(app.UIFigure);
            app.PresenceLabel.Text = 'Presence';
            app.brilliance = uislider(app.UIFigure);
            app.brilliance.Limits = [-50 50];
            app.BrillianceLabel = uilabel(app.UIFigure);
            app.BrillianceLabel.Text = 'Brilliance';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = 'dB';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = 'dB';
            app.dBLabel_6 = uilabel(app.UIFigure);
            app.dBLabel_6.Text = 'dB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecualizador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end